classdef app_twoneuron < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GeneratenewmatrixButton      matlab.ui.control.Button
        GeneratenewsimulationButton  matlab.ui.control.Button
        W_rnnPanel                   matlab.ui.container.Panel
        wrnn11                       matlab.ui.control.NumericEditField
        wrnn12                       matlab.ui.control.NumericEditField
        wrnn21                       matlab.ui.control.NumericEditField
        wrnn22                       matlab.ui.control.NumericEditField
        EigenvaluesLabel             matlab.ui.control.Label
        Eigenvector1Label            matlab.ui.control.Label
        Eigenvector2Label            matlab.ui.control.Label
        DalesButton                  matlab.ui.control.StateButton
        WPanel                       matlab.ui.container.Panel
        w11                          matlab.ui.control.NumericEditField
        w12                          matlab.ui.control.NumericEditField
        w21                          matlab.ui.control.NumericEditField
        w22                          matlab.ui.control.NumericEditField
        EigenvaluesLabel_w           matlab.ui.control.Label
        Eigenvector1Label_w          matlab.ui.control.Label
        Eigenvector2Label_w          matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        LogButton                    matlab.ui.control.StateButton
        AutoButton                   matlab.ui.control.Button
        LogButton_2                  matlab.ui.control.StateButton
        AutoButton_2                 matlab.ui.control.Button
        LogButton_3                  matlab.ui.control.StateButton
        AutoButton_3                 matlab.ui.control.Button
        MaxEigEditFieldLabel         matlab.ui.control.Label
        MaxEigEditField              matlab.ui.control.NumericEditField
        AlphaEditFieldLabel          matlab.ui.control.Label
        AlphaEditField               matlab.ui.control.NumericEditField
        NoisestdEditFieldLabel       matlab.ui.control.Label
        NoisestdEditField            matlab.ui.control.NumericEditField
        biasPanel                    matlab.ui.container.Panel
        bias1                        matlab.ui.control.NumericEditField
        bias2                        matlab.ui.control.NumericEditField
        newbias                      matlab.ui.control.Button
        FulltimeButton               matlab.ui.control.StateButton
        TodogetlinkaxesworkinguseapanelinsteadoffigureLabel  matlab.ui.control.Label
        crnnstudyLabel               matlab.ui.control.Label
        initialconditionPanel        matlab.ui.container.Panel
        init1                        matlab.ui.control.NumericEditField
        init2                        matlab.ui.control.NumericEditField
        newinit                      matlab.ui.control.Button
        NonlinearityPanel            matlab.ui.container.Panel
        reluButton                   matlab.ui.control.StateButton
        expEditFieldLabel            matlab.ui.control.Label
        reluExp                      matlab.ui.control.NumericEditField
        sigmoidButton                matlab.ui.control.StateButton
        noneButton                   matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        Wrnn % matrix
        W % decayed matrix.
        decay % Description
        nsteps % number of time points for simulation
        %Axes1 matlab.graphics.axis.Axes
        %Axes2 matlab.graphics.axis.Axes
        %Axes3 matlab.graphics.axis.Axes
    end
    
    methods (Access = private)
        function update_wrnn(app)
            %% WRNN
            e       = eig(app.Wrnn);
            [V,D]   = eig(app.Wrnn);
            
            if imag(e(2)) == 0
                str0 = sprintf('eigenvalues: \n %+4.2f, %+4.2f', ...
                    real(e(1)), real(e(2)));    
            else
                str0 = sprintf('eigenvalues:\n r=%+4.2f \n angle =%+4.2f deg', ...
                    abs(e(1)),angle(e(1))/pi*180);
            end
            app.EigenvaluesLabel.Text = str0;       
        
            app.wrnn11.Value = app.Wrnn(1,1);
            app.wrnn12.Value = app.Wrnn(1,2);
            app.wrnn21.Value = app.Wrnn(2,1);
            app.wrnn22.Value = app.Wrnn(2,2);
        
            if (imag(V(1,1)) == 0) && (imag(V(2,1)) == 0) 
                str2 = sprintf('Eigenvector1: \n %+4.2f \n %+4.2f', ...
                    real(V(1,1)), real(V(2,1)));
                str3 = sprintf('Eigenvector2: \n %+4.2f \n %+4.2f', ...
                    real(V(1,2)), real(V(2,2)));
            else
                str2 = sprintf('Eigenvector1: \n %+4.2f%+4.2f i \n %+4.2f%+4.2f i', ...
                    real(V(1,1)),imag(V(1,1)),  real(V(2,1)),imag(V(2,1)));
                str3 = sprintf('Eigenvector2: \n %+4.2f%+4.2f i \n %+4.2f%+4.2f i', ...
                    real(V(1,2)),imag(V(1,2)),  real(V(2,2)),imag(V(2,2)));
            end
            app.Eigenvector1Label.Text = str2;       
            app.Eigenvector2Label.Text = str3;
        end
        
        function update_decayedW(app,fromwrnn)
            if fromwrnn
                app.W = app.decay + app.AlphaEditField.Value*app.Wrnn; % change W but do not save in appdata
            end
           
            e       = eig(app.W);
            [V,D]   = eig(app.W);
            
            if imag(e(2)) == 0
                str0 = sprintf('eigenvalues: \n %+4.2f, %+4.2f', ...
                    real(e(1)), real(e(2)));    
            else
                str0 = sprintf('eigenvalues:\n r=%+4.2f \n angle =%+4.2f deg', ...
                    abs(e(1)),angle(e(1))/pi*180);
            end
            app.EigenvaluesLabel_w.Text = str0;       
        
            app.w11.Value = app.W(1,1);
            app.w12.Value = app.W(1,2);
            app.w21.Value = app.W(2,1);
            app.w22.Value = app.W(2,2);
        
            if (imag(V(1,1)) == 0) && (imag(V(2,1)) == 0) 
                str2 = sprintf('Eigenvector1: \n %+4.2f \n %+4.2f', ...
                    real(V(1,1)), real(V(2,1)));
                str3 = sprintf('Eigenvector2: \n %+4.2f \n %+4.2f', ...
                    real(V(1,2)), real(V(2,2)));
            else
                str2 = sprintf('Eigenvector1: \n %+4.2f%+4.2f i \n %+4.2f%+4.2f i', ...
                    real(V(1,1)),imag(V(1,1)),  real(V(2,1)),imag(V(2,1)));
                str3 = sprintf('Eigenvector2: \n %+4.2f%+4.2f i \n %+4.2f%+4.2f i', ...
                    real(V(1,2)),imag(V(1,2)),  real(V(2,2)),imag(V(2,2)));
            end
            app.Eigenvector1Label_w.Text = str2;       
            app.Eigenvector2Label_w.Text = str3;
        end
        
        function x = relu(~,x)
            x(x<0) = 0;
        end
        
        function generateNewSim(app)
            NoiseStd    = app.NoisestdEditField.Value;
            alpha       = app.AlphaEditField.Value;
            b           = [app.bias1.Value; app.bias2.Value];
            
            % run through recurrent matrix
            r(1,:)  = [app.init1.Value; app.init2.Value] ;
            r1(1,:) = [app.init1.Value; app.init2.Value] ;
            r2(1,:) = [app.init1.Value; app.init2.Value] ;
            for iter = 2:app.nsteps
                r(iter,:)  = app.decay*r(iter-1,:)' + ...
                    alpha*(app.Wrnn*r(iter-1,:)' + b);
                
                if app.reluButton.Value
                    r1(iter,:) = app.decay*r1(iter-1,:)' + ...
                        alpha*relu(app,app.Wrnn*r1(iter-1,:)' + b).^(app.reluExp.Value);
                    r2(iter,:) = app.decay*r2(iter-1,:)' + ...
                        alpha*relu(app,app.Wrnn*r2(iter-1,:)'+ ...
                            NoiseStd * sqrt(2/alpha)*rand(2,1) + b).^(app.reluExp.Value) ;
                elseif app.sigmoidButton.Value
                    r1(iter,:) = app.decay*r1(iter-1,:)' + ...
                        alpha*sigmoid(app,app.Wrnn*r1(iter-1,:)'+ b);
                    r2(iter,:) = app.decay*r2(iter-1,:)' + ...
                        alpha*sigmoid(app,app.Wrnn*r2(iter-1,:)' + ...
                            NoiseStd * sqrt(2/alpha)*rand(2,1) + b);
                else
                    r1(iter,:) = app.decay*r1(iter-1,:)' + ...
                        alpha*(app.Wrnn*r1(iter-1,:)'+ b);
                    r2(iter,:) = app.decay*r2(iter-1,:)' + ...
                        alpha*(app.Wrnn*r2(iter-1,:)' + ...
                            NoiseStd * sqrt(2/alpha)*rand(2,1) + b);
                end
            end
            
            cla(app.UIAxes); hold(app.UIAxes,'on');
            plot(app.UIAxes,r(:,1),'b'); plot(app.UIAxes,r(:,2),'r');
            legend(app.UIAxes,{'Exc', 'Inh'});
            
            cla(app.UIAxes_2); hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,r1(:,1),'b'); plot(app.UIAxes_2,r1(:,2),'r');
            legend(app.UIAxes_2,{'Exc', 'Inh'});
            
            cla(app.UIAxes_3); hold(app.UIAxes_3,'on');
            plot(app.UIAxes_3,r2(:,1),'b'); plot(app.UIAxes_3,r2(:,2),'r');
            legend(app.UIAxes_3,{'Exc', 'Inh'});
            
            % linkaxes([app.UIAxes, app.UIAxes_2, app.UIAxes_3],'x');
            
            if app.FulltimeButton.Value == 1
                xlim(app.UIAxes,[0,app.nsteps])        
                xlim(app.UIAxes_2,[0,app.nsteps])        
                xlim(app.UIAxes_3,[0,app.nsteps])        
            else
                xlim(app.UIAxes,[0,app.nsteps/10])    
                xlim(app.UIAxes_2,[0,app.nsteps/10])        
                xlim(app.UIAxes_3,[0,app.nsteps/10])        
            end

        end
        
        function applyDale(app)
            if app.DalesButton.Value
                app.Wrnn = abs(app.Wrnn)*[1,0;0,-1];
            end
        end
        
        function y = sigmoid(~,x)
            y = 1./(1+exp(-x));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % linkaxes([app.UIAxes, app.UIAxes_2, app.UIAxes_3],'x');
            app.Wrnn        = zeros(2,2); % matrix
            app.W           = zeros(2,2);% decayed matrix.
            app.decay       = (1-app.AlphaEditField.Value)*eye(2,2);
            app.init1.Value = rand(1,1); % random (positive) initialization
            app.init2.Value = rand(1,1); % random (positive) initialization
            app.nsteps = 1000;% number of time points for simulation
        end

        % Value changed function: LogButton
        function LogButtonValueChanged(app, event)
            if app.LogButton.Value == 1
                set(app.UIAxes,'Yscale','log')        % change to log scale
            else
                set(app.UIAxes,'Yscale','linear')        % change to log scale
            end
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            axis(app.UIAxes,'auto y')
        end

        % Value changed function: LogButton_2
        function LogButton_2ValueChanged(app, event)
            if app.LogButton_2.Value == 1
                set(app.UIAxes_2,'Yscale','log')        % change to log scale
            else
                set(app.UIAxes_2,'Yscale','linear')        % change to log scale
            end
        end

        % Value changed function: LogButton_3
        function LogButton_3ValueChanged(app, event)
            if app.LogButton_3.Value == 1
                set(app.UIAxes_3,'Yscale','log')        % change to log scale
            else
                set(app.UIAxes_3,'Yscale','linear')        % change to log scale
            end            
        end

        % Value changed function: FulltimeButton
        function FulltimeButtonValueChanged(app, event)
            %linkaxes([app.UIAxes, app.UIAxes_2, app.UIAxes_3],'x');
            if app.FulltimeButton.Value == 1
                xlim(app.UIAxes,[0,app.nsteps])        
                xlim(app.UIAxes_2,[0,app.nsteps])        
                xlim(app.UIAxes_3,[0,app.nsteps])        
            else
                xlim(app.UIAxes,[0,app.nsteps/10])    
                xlim(app.UIAxes_2,[0,app.nsteps/10])        
                xlim(app.UIAxes_3,[0,app.nsteps/10])        
            end
        end

        % Button pushed function: GeneratenewmatrixButton
        function GeneratenewmatrixButtonPushed(app, event)
            A = rand(2,2); % select a positive matrix
            if app.DalesButton.Value
                app.Wrnn = app.MaxEigEditField.Value * A*[1,0;0,-1]; 
            else
                app.Wrnn = app.MaxEigEditField.Value * A;
            end
            
            update_wrnn(app);    
            update_decayedW(app,1);
            generateNewSim(app);
        end

        % Value changed function: bias1
        function bias1ValueChanged(app, event)
            % generateNewSim(app);
        end

        % Value changed function: bias2
        function bias2ValueChanged(app, event)
            % generateNewSim(app);
        end

        % Value changed function: wrnn11
        function wrnn11ValueChanged(app, event)
            app.Wrnn(1,1) = app.wrnn11.Value;
            applyDale(app);
            update_wrnn(app);
            update_decayedW(app,1);
        end

        % Value changed function: wrnn12
        function wrnn12ValueChanged(app, event)
            app.Wrnn(1,2) = app.wrnn12.Value;
            applyDale(app);
            update_wrnn(app);
            update_decayedW(app,1);
        end

        % Value changed function: wrnn21
        function wrnn21ValueChanged(app, event)
            app.Wrnn(2,1) = app.wrnn21.Value;
            applyDale(app);
            update_wrnn(app);
            update_decayedW(app,1);
        end

        % Value changed function: wrnn22
        function wrnn22ValueChanged(app, event)
            app.Wrnn(2,2) = app.wrnn22.Value;
            applyDale(app);
            update_wrnn(app);
            update_decayedW(app,1);
        end

        % Value changed function: w11
        function w11ValueChanged(app, event)
            app.W(1,1) = app.w11.Value;
            update_decayedW(app,0);
        end

        % Value changed function: w12
        function w12ValueChanged(app, event)
            app.W(1,2) = app.w12.Value;
            update_decayedW(app,0);
        end

        % Value changed function: w21
        function w21ValueChanged(app, event)
            app.W(2,1) = app.w21.Value;
            update_decayedW(app,0);            
        end

        % Value changed function: w22
        function w22ValueChanged(app, event)
            app.W(2,2) = app.w22.Value;
            update_decayedW(app,0);
        end

        % Value changed function: DalesButton
        function DalesButtonValueChanged(app, event)
            if app.DalesButton.Value % new value
                applyDale(app);
                update_wrnn(app);
                update_decayedW(app,1);
                generateNewSim(app);
            end
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            app.decay = (1-app.AlphaEditField.Value)*eye(2,2);
        end

        % Button pushed function: GeneratenewsimulationButton
        function GeneratenewsimulationButtonPushed(app, event)
            generateNewSim(app);
        end

        % Button pushed function: newinit
        function newinitPushed(app, event)
            app.init1.Value = rand(1);
            app.init2.Value = rand(1);
            generateNewSim(app);
        end

        % Button pushed function: newbias
        function newbiasPushed(app, event)
            app.bias1.Value = rand(1);
            app.bias2.Value = rand(1);
            generateNewSim(app);
        end

        % Button pushed function: AutoButton_2
        function AutoButton_2Pushed(app, event)
            axis(app.UIAxes_2,'auto y')
        end

        % Button pushed function: AutoButton_3
        function AutoButton_3Pushed(app, event)
            axis(app.UIAxes_3,'auto y')
        end

        % Value changed function: reluButton
        function reluButtonValueChanged(app, event)
            if app.reluButton.Value
                app.sigmoidButton.Value = 0;
                app.noneButton.Value = 0;
            else
                app.sigmoidButton.Value = 0;
                app.noneButton.Value = 1;
            end
        end

        % Callback function
        function sigmoidButtonPushed(app, event)
            
        end

        % Callback function
        function noneButtonPushed(app, event)
            
        end

        % Value changed function: sigmoidButton
        function sigmoidButtonValueChanged(app, event)
            if app.sigmoidButton.Value
                app.reluButton.Value = 0;
                app.noneButton.Value = 0;
            else
                app.reluButton.Value = 0;
                app.noneButton.Value = 1;
            end
        end

        % Value changed function: noneButton
        function noneButtonValueChanged(app, event)
            if app.noneButton.Value
                app.reluButton.Value    = 0;
                app.sigmoidButton.Value = 0;
            else
                app.noneButton.Value    = 1; % pop back up
                app.reluButton.Value    = 0;
                app.sigmoidButton.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeneratenewmatrixButton = uibutton(app.UIFigure, 'push');
            app.GeneratenewmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratenewmatrixButtonPushed, true);
            app.GeneratenewmatrixButton.Text = 'Generate new matrix';
            app.GeneratenewsimulationButton = uibutton(app.UIFigure, 'push');
            app.GeneratenewsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratenewsimulationButtonPushed, true);
            app.GeneratenewsimulationButton.Text = 'Generate new simulation';
            app.W_rnnPanel = uipanel(app.UIFigure);
            app.W_rnnPanel.Title = 'W_rnn';
            app.wrnn11 = uieditfield(app.W_rnnPanel, 'numeric');
            app.wrnn11.ValueChangedFcn = createCallbackFcn(app, @wrnn11ValueChanged, true);
            app.wrnn12 = uieditfield(app.W_rnnPanel, 'numeric');
            app.wrnn12.ValueChangedFcn = createCallbackFcn(app, @wrnn12ValueChanged, true);
            app.wrnn21 = uieditfield(app.W_rnnPanel, 'numeric');
            app.wrnn21.ValueChangedFcn = createCallbackFcn(app, @wrnn21ValueChanged, true);
            app.wrnn22 = uieditfield(app.W_rnnPanel, 'numeric');
            app.wrnn22.ValueChangedFcn = createCallbackFcn(app, @wrnn22ValueChanged, true);
            app.EigenvaluesLabel = uilabel(app.W_rnnPanel);
            app.EigenvaluesLabel.Text = 'Eigenvalues';
            app.Eigenvector1Label = uilabel(app.W_rnnPanel);
            app.Eigenvector1Label.Text = 'Eigenvector1';
            app.Eigenvector2Label = uilabel(app.W_rnnPanel);
            app.Eigenvector2Label.Text = 'Eigenvector2';
            app.DalesButton = uibutton(app.W_rnnPanel, 'state');
            app.DalesButton.ValueChangedFcn = createCallbackFcn(app, @DalesButtonValueChanged, true);
            app.DalesButton.Text = 'Dale''s';
            app.WPanel = uipanel(app.UIFigure);
            app.WPanel.Title = 'with decay: (1-\alpha) I + \alpha W_rnn';
            app.w11 = uieditfield(app.WPanel, 'numeric');
            app.w11.ValueChangedFcn = createCallbackFcn(app, @w11ValueChanged, true);
            app.w12 = uieditfield(app.WPanel, 'numeric');
            app.w12.ValueChangedFcn = createCallbackFcn(app, @w12ValueChanged, true);
            app.w21 = uieditfield(app.WPanel, 'numeric');
            app.w21.ValueChangedFcn = createCallbackFcn(app, @w21ValueChanged, true);
            app.w22 = uieditfield(app.WPanel, 'numeric');
            app.w22.ValueChangedFcn = createCallbackFcn(app, @w22ValueChanged, true);
            app.EigenvaluesLabel_w = uilabel(app.WPanel);
            app.EigenvaluesLabel_w.Text = 'Eigenvalues';
            app.Eigenvector1Label_w = uilabel(app.WPanel);
            app.Eigenvector1Label_w.Text = 'Eigenvector1';
            app.Eigenvector2Label_w = uilabel(app.WPanel);
            app.Eigenvector2Label_w.Text = 'Eigenvector2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Decay + Dales law')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '+ nonlinearity')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '+ noise')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Y')
            app.LogButton = uibutton(app.UIFigure, 'state');
            app.LogButton.ValueChangedFcn = createCallbackFcn(app, @LogButtonValueChanged, true);
            app.LogButton.Text = 'Log';
            app.AutoButton = uibutton(app.UIFigure, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.LogButton_2 = uibutton(app.UIFigure, 'state');
            app.LogButton_2.ValueChangedFcn = createCallbackFcn(app, @LogButton_2ValueChanged, true);
            app.LogButton_2.Text = 'Log';
            app.AutoButton_2 = uibutton(app.UIFigure, 'push');
            app.AutoButton_2.ButtonPushedFcn = createCallbackFcn(app, @AutoButton_2Pushed, true);
            app.AutoButton_2.Text = 'Auto';
            app.LogButton_3 = uibutton(app.UIFigure, 'state');
            app.LogButton_3.ValueChangedFcn = createCallbackFcn(app, @LogButton_3ValueChanged, true);
            app.LogButton_3.Text = 'Log';
            app.AutoButton_3 = uibutton(app.UIFigure, 'push');
            app.AutoButton_3.ButtonPushedFcn = createCallbackFcn(app, @AutoButton_3Pushed, true);
            app.AutoButton_3.Text = 'Auto';
            app.MaxEigEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEigEditFieldLabel.Text = 'MaxEig';
            app.MaxEigEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEigEditField.Limits = [0 100];
            app.MaxEigEditField.Value = 1;
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField.Limits = [0 1];
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AlphaEditField.Value = 0.1;
            app.NoisestdEditFieldLabel = uilabel(app.UIFigure);
            app.NoisestdEditFieldLabel.Text = 'Noise std';
            app.NoisestdEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoisestdEditField.Limits = [0.0001 100];
            app.NoisestdEditField.Value = 1;
            app.biasPanel = uipanel(app.UIFigure);
            app.biasPanel.Title = 'bias';
            app.bias1 = uieditfield(app.biasPanel, 'numeric');
            app.bias1.ValueChangedFcn = createCallbackFcn(app, @bias1ValueChanged, true);
            app.bias2 = uieditfield(app.biasPanel, 'numeric');
            app.bias2.ValueChangedFcn = createCallbackFcn(app, @bias2ValueChanged, true);
            app.newbias = uibutton(app.biasPanel, 'push');
            app.newbias.ButtonPushedFcn = createCallbackFcn(app, @newbiasPushed, true);
            app.newbias.Text = 'new';
            app.FulltimeButton = uibutton(app.UIFigure, 'state');
            app.FulltimeButton.ValueChangedFcn = createCallbackFcn(app, @FulltimeButtonValueChanged, true);
            app.FulltimeButton.Text = 'Full time';
            app.TodogetlinkaxesworkinguseapanelinsteadoffigureLabel = uilabel(app.UIFigure);
            app.TodogetlinkaxesworkinguseapanelinsteadoffigureLabel.Text = 'Todo: get linkaxes working (use a panel instead of figure: https://www.mathworks.com/matlabcentral/answers/535824-do-functions-like-linkaxes-or-linkprop-work-with-uifigure-in-appdesigner)';
            app.crnnstudyLabel = uilabel(app.UIFigure);
            app.crnnstudyLabel.Text = '(c) rnnstudy';
            app.initialconditionPanel = uipanel(app.UIFigure);
            app.initialconditionPanel.Title = 'initial condition';
            app.init1 = uieditfield(app.initialconditionPanel, 'numeric');
            app.init2 = uieditfield(app.initialconditionPanel, 'numeric');
            app.newinit = uibutton(app.initialconditionPanel, 'push');
            app.newinit.ButtonPushedFcn = createCallbackFcn(app, @newinitPushed, true);
            app.newinit.Text = 'new';
            app.NonlinearityPanel = uipanel(app.UIFigure);
            app.NonlinearityPanel.Title = 'Nonlinearity';
            app.reluButton = uibutton(app.NonlinearityPanel, 'state');
            app.reluButton.ValueChangedFcn = createCallbackFcn(app, @reluButtonValueChanged, true);
            app.reluButton.Text = 'relu';
            app.reluButton.Value = true;
            app.expEditFieldLabel = uilabel(app.NonlinearityPanel);
            app.expEditFieldLabel.Text = 'exp';
            app.reluExp = uieditfield(app.NonlinearityPanel, 'numeric');
            app.reluExp.Limits = [0 Inf];
            app.reluExp.Value = 1;
            app.sigmoidButton = uibutton(app.NonlinearityPanel, 'state');
            app.sigmoidButton.ValueChangedFcn = createCallbackFcn(app, @sigmoidButtonValueChanged, true);
            app.sigmoidButton.Text = 'sigmoid';
            app.noneButton = uibutton(app.NonlinearityPanel, 'state');
            app.noneButton.ValueChangedFcn = createCallbackFcn(app, @noneButtonValueChanged, true);
            app.noneButton.Text = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_twoneuron

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end